classdef RectilinearECPControl < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TrajectoryParametersPanel      matlab.ui.container.Panel
        TabGroup                       matlab.ui.container.TabGroup
        OpenLoopTab                    matlab.ui.container.Tab
        AmplitudevoltsEditField        matlab.ui.control.NumericEditField
        AmplitudevoltsEditFieldLabel   matlab.ui.control.Label
        ClosedLoopTab                  matlab.ui.container.Tab
        AmplitudeencodercountsEditField  matlab.ui.control.NumericEditField
        AmplitudeencodercountsEditFieldLabel  matlab.ui.control.Label
        StartButton                    matlab.ui.control.Button
        RepsEditField                  matlab.ui.control.NumericEditField
        RepsEditFieldLabel             matlab.ui.control.Label
        FeedbackVariableDropDownLabel  matlab.ui.control.Label
        FrequencyEditField             matlab.ui.control.NumericEditField
        FrequencyLabel                 matlab.ui.control.Label
        TrajectoryTypeDropDown         matlab.ui.control.DropDown
        TrajectoryTypeDropDownLabel    matlab.ui.control.Label
        FeedbackVariableDropDown       matlab.ui.control.DropDown
        ControlGainsPanel              matlab.ui.container.Panel
        UpdateGainsButton              matlab.ui.control.Button
        DerivativeEditField            matlab.ui.control.NumericEditField
        DerivativeEditFieldLabel       matlab.ui.control.Label
        IntegralEditField              matlab.ui.control.NumericEditField
        IntegralEditFieldLabel         matlab.ui.control.Label
        ProportionalEditField          matlab.ui.control.NumericEditField
        ProportionalEditFieldLabel     matlab.ui.control.Label
        CommunicationControlsPanel     matlab.ui.container.Panel
        SelectPortDropDownLabel        matlab.ui.control.Label
        StatusLamp                     matlab.ui.control.Lamp
        DisconnectButton               matlab.ui.control.Button
        ConnectButton                  matlab.ui.control.Button
        SelectPortDropDown             matlab.ui.control.DropDown
        ScanPortsButton                matlab.ui.control.Button
        ClearDataButton                matlab.ui.control.Button
        SaveDataButton                 matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global t_received;
            global v_received;
            global cart1pos;
            global cart2pos;
            global target_value;
            global duty_cycle;
            global time_stamp;
            global data_pre;
            data_pre = 5E4;
            t_received = zeros(1, data_pre); % preallocating variables
            v_received = zeros(1, data_pre);
            cart1pos = zeros(1, data_pre);
            cart2pos = zeros(1, data_pre);
            target_value = zeros(1, data_pre);
            duty_cycle = zeros(1, data_pre);
            time_stamp = 'null';
            xlim(app.UIAxes,[0 10]); % setting up axes
            ylim(app.UIAxes,[-7000 7000]);
            yyaxis(app.UIAxes, "right");
            ylim(app.UIAxes, [-5000, 5000]);
            ylabel(app.UIAxes,'Duty Cycle');
            app.StatusLamp.Color = [1 0 0]; % setting lamp color
            plot(app.UIAxes,0,0,'k',0,0,'r',0,0,'b',0,0,'m');
            legend(app.UIAxes,{'Cart 1','Cart 2','Target','Duty Cycle'},'Location','southwest');
            legend(app.UIAxes,'boxoff');
        end

        % Button pushed function: ScanPortsButton
        function ScanPortsButtonPushed(app, event)
            app.SelectPortDropDown.Items = serialportlist; % populating the serialport dropdown
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            global teensy_connect;
            global connectbit;
            teensy_connect = serialport(app.SelectPortDropDown.Value,115200); % connecting to the microcontroller
            connectbit = 1; 
            app.StatusLamp.Color = [0 1 0]; % change the lamp color green
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            global teensy_connect;
            global connectbit;
            writeline(teensy_connect,"z"); % send a signal over to the teensy to restart, then delay
            pause(0.1);
            flush(teensy_connect);
            clear teensy_connect; % clearing the serialport object
            connectbit = 0; % flipping the bit
            app.AmplitudevoltsEditField.Value = 0;
            app.FrequencyEditField.Value = 0;
            app.RepsEditField.Value = 0;
            app.DerivativeEditField.Value = 0;
            app.IntegralEditField.Value = 0;
            app.ProportionalEditField.Value = 0;
            app.StatusLamp.Color = [1 0 0]; % lamp to red
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            global teensy_connect;
            global startbit;
            global connectbit;
            global t_received;
            global v_received;
            global cart1pos;
            global cart2pos;
            global target_value;
            global duty_cycle;
            global time_stamp;
            if app.TrajectoryTypeDropDown.Value == "Delta"
                output1 = "d";
            elseif app.TrajectoryTypeDropDown.Value == "Square Wave"
                output1 = "q";
            elseif app.TrajectoryTypeDropDown.Value == "Sine Wave"
                output1 = "s";
            elseif app.TrajectoryTypeDropDown.Value == "Triangle Wave"
                output1 = "t";
            elseif app.TrajectoryTypeDropDown.Value == "Sawtooth Wave"
                output1 = "w";
            end
            % output1 = string(app.TrajectoryTypeDropDown.Value); %which disturbance signal to send
%             if app.LoopDropDown.Value == "Open"
%                 output2 = "o";
%             elseif app.LoopDropDown.Value == "Closed"
%                 output2 = "c";           
%             end
            if app.FeedbackVariableDropDown.Value == "Encoder 1"
                output3 = "o";
            elseif app.FeedbackVariableDropDown.Value == "Encoder 2"
                output3 = "t";
            end
            output4 = string(app.FrequencyEditField.Value); %frequency value of disturbance
            % toggling between open and closed loop control
            if app.TabGroup.SelectedTab == app.OpenLoopTab
                output2 = "o";
                voltage = 4096*app.AmplitudevoltsEditField.Value/5; % converting voltage to duty cycle
                output5 = string(voltage);
            elseif app.TabGroup.SelectedTab == app.ClosedLoopTab
                output2 = "c";
                output5 = string(app.AmplitudeencodercountsEditField.Value);
            end
            % output5 = string(app.AmplitudevoltsEditField.Value); %amplitude value of disturbance
            output6 = string(app.RepsEditField.Value); %repetitions of disturbance
            deli = ";"; %delimiter
            output3 = strcat("d", deli, output1, deli, output2, deli, output3, deli, output4, deli, output5, deli, output6, deli); %string construction
            writeline(teensy_connect, output3); %string sent to Teensy
            pause(0.1); %pausing to allow for serial transmission
            if connectbit == 1 % checks for serial connection
                startbit = 1; % bit flip
                i = 1;
                flush(teensy_connect,"input");
            end
            if startbit == 1
                pause(0.01);
                tempText=convertStringsToChars(readline(teensy_connect)); % pulling data from teensy
                assignin("base","tempText",tempText);
                ind_ref = strfind(tempText,':'); % indices of the delimiters
                t_start = str2double(tempText(ind_ref(4)+1:end))*10^-3; % pulling out the start time
                assignin("base","t_start",t_start);
                k = 1;
            end
            period = 1/app.FrequencyEditField.Value;
            xlim(app.UIAxes,[0 (period*app.RepsEditField.Value+1)]);
            while startbit == 1
                tempText=convertStringsToChars(readline(teensy_connect));
                ind_ref = strfind(tempText,':');
                cart1pos(i) = str2double(tempText(1:ind_ref(1)-1)); % cart 1 encoder position
                cart2pos(i) = str2double(tempText(ind_ref(1)+1:ind_ref(2)-1)); % cart 2 encoder position
                target_value(i) = str2double(tempText(ind_ref(2)+1:ind_ref(3)-1)); % target value
                duty_cycle(i) = str2double(tempText(ind_ref(3)+1:ind_ref(4)-1));
                t_received(i) = (str2double(tempText(ind_ref(4)+1:end)))*10^-3-t_start; % pulling out the time index convert to seconds
                % ind_ref = strfind(tempText,':');
                % v_received(i) = str2double(tempText(1:ind_ref-1))*5.0/1023;
                % t_received(i) = str2double(tempText(ind_ref+1:end))*10^-3 - t_start;
                % plot(app.UIAxes, t_received(1:i), v_received(1:i));
                legend(app.UIAxes,'off');
                if mod(i,100) == 0 %updates the plot every 100 milliseconds
                    yyaxis(app.UIAxes,'left');
                    if output2 == 'o'
                       plot(app.UIAxes, t_received(1:i),cart1pos(1:i),'k',t_received(1:i),cart2pos(1:i),'r'); 
                    else
                       plot(app.UIAxes, t_received(1:i),cart1pos(1:i),'k',t_received(1:i),cart2pos(1:i),'r',t_received(1:i),target_value(1:i),'b');
                    end
                    % assignin("base","i",i);
                    yyaxis(app.UIAxes,'right');
                    if output2 == 'o'
                        plot(app.UIAxes,t_received(1:i),target_value(1:i),'m');
                    else
                        plot(app.UIAxes,t_received(1:i),-duty_cycle(1:i),'m');
                    end
                    
                    drawnow;
                    % hold on;
                    k = i+1;
                end
                i = i+1;
                if i>(period*app.RepsEditField.Value*1000)
                    startbit = 0;
                    time_stamp = datetime('now');
                    if output2 == "o"
                        legend(app.UIAxes,{'Cart 1','Cart 2','Motor Voltage'},'Location','southwest');
                    else
                        legend(app.UIAxes,{'Cart 1','Cart 2','Target','Motor Voltage'},'Location','southwest');
                    end
                    legend(app.UIAxes,'boxoff');
                    flush(teensy_connect,"input");
                end
            end
        end

        % Button pushed function: UpdateGainsButton
        function UpdateGainsButtonPushed(app, event)
            global teensy_connect;
            output1 = string(app.ProportionalEditField.Value); %proportional gain
            output2 = string(app.IntegralEditField.Value); %integral gain
            output3 = string(app.DerivativeEditField.Value); %derivative gain
            deli = ";"; %delimiter
            gain_string = strcat("g", deli,output1, deli, output2, deli, output3, deli); %construct the string
            writeline(teensy_connect, gain_string); %send gain values to Teensy
        end

        % Button pushed function: SaveDataButton
        function SaveDataButtonPushed(app, event)
            global cart1pos;
            global cart2pos;
            global target_value;
            global duty_cycle;
            global t_received;
            global time_stamp;
            n = 1000*app.RepsEditField.Value/app.FrequencyEditField.Value;
            i = 1:n;
            runParameters = struct('P_gain',app.ProportionalEditField.Value,'I_gain',app.IntegralEditField.Value,'D_gain',app.DerivativeEditField.Value,'Waveform',app.TrajectoryTypeDropDown.Value,'Frequency',app.FrequencyEditField.Value,'Amplitude',app.AmplitudevoltsEditField.Value,'Reps',app.RepsEditField.Value);
            name = replace(string(time_stamp),':','_');
            enc1 = cart1pos(1:n);
            enc2 = cart2pos(1:n);
            target_v = target_value(1:n);
            duty = duty_cycle(1:n);
            time = t_received(1:n);
            save(['ControlExp'+name+'.mat'],'enc1','enc2','target_v','duty','time','runParameters');
            % T = table(i', time', enc1', enc2', target_v','VariableNames',{'Sample','Time','Encoder 1 Pos','Encoder 2 Pos','Control Effort'});
            % writetable(T,['ControlExp'+name+'.txt'],'Delimiter','\t');
            % save([name+'.mat'],'cart1pos','cart2pos','target_value','duty_cycle','t_received','runParameters');
        end

        % Button pushed function: ClearDataButton
        function ClearDataButtonPushed(app, event)
            global cart1pos;
            global cart2pos;
            global target_value;
            global duty_cycle;
            global t_received;
            global data_pre;
            global time_stamp;
            clear cart1pos;
            clear cart2pos;
            clear target_value;
            clear duty_cycle;
            clear t_received;
            cart1pos = zeros(1,data_pre);
            cart2pos = zeros(1,data_pre);
            t_received = zeros(1,data_pre);
            time_stamp = 'null';
            cla(app.UIAxes);
            yyaxis(app.UIAxes,'left');
            cla(app.UIAxes);
            drawnow;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'System Response')
            xlabel(app.UIAxes, 'Time (seconds)')
            ylabel(app.UIAxes, 'Encoder Counts')
            app.SaveDataButton = uibutton(app.UIFigure, 'push');
            app.SaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDataButtonPushed, true);
            app.SaveDataButton.Text = 'Save Data';
            app.ClearDataButton = uibutton(app.UIFigure, 'push');
            app.ClearDataButton.ButtonPushedFcn = createCallbackFcn(app, @ClearDataButtonPushed, true);
            app.ClearDataButton.Text = 'Clear Data';
            app.CommunicationControlsPanel = uipanel(app.UIFigure);
            app.CommunicationControlsPanel.Title = 'Communication Controls';
            app.ScanPortsButton = uibutton(app.CommunicationControlsPanel, 'push');
            app.ScanPortsButton.ButtonPushedFcn = createCallbackFcn(app, @ScanPortsButtonPushed, true);
            app.ScanPortsButton.Text = 'Scan Ports';
            app.SelectPortDropDown = uidropdown(app.CommunicationControlsPanel);
            app.SelectPortDropDown.Items = {'Press Scan'};
            app.SelectPortDropDown.Value = 'Press Scan';
            app.ConnectButton = uibutton(app.CommunicationControlsPanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.DisconnectButton = uibutton(app.CommunicationControlsPanel, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Text = 'Disconnect';
            app.StatusLamp = uilamp(app.CommunicationControlsPanel);
            app.SelectPortDropDownLabel = uilabel(app.CommunicationControlsPanel);
            app.SelectPortDropDownLabel.Text = 'Select Port:';
            app.ControlGainsPanel = uipanel(app.UIFigure);
            app.ControlGainsPanel.Title = 'Control Gains';
            app.ProportionalEditFieldLabel = uilabel(app.ControlGainsPanel);
            app.ProportionalEditFieldLabel.Text = 'Proportional:';
            app.ProportionalEditField = uieditfield(app.ControlGainsPanel, 'numeric');
            app.IntegralEditFieldLabel = uilabel(app.ControlGainsPanel);
            app.IntegralEditFieldLabel.Text = 'Integral:';
            app.IntegralEditField = uieditfield(app.ControlGainsPanel, 'numeric');
            app.DerivativeEditFieldLabel = uilabel(app.ControlGainsPanel);
            app.DerivativeEditFieldLabel.Text = 'Derivative:';
            app.DerivativeEditField = uieditfield(app.ControlGainsPanel, 'numeric');
            app.UpdateGainsButton = uibutton(app.ControlGainsPanel, 'push');
            app.UpdateGainsButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateGainsButtonPushed, true);
            app.UpdateGainsButton.Text = 'Update Gains';
            app.TrajectoryParametersPanel = uipanel(app.UIFigure);
            app.TrajectoryParametersPanel.Title = 'Trajectory Parameters';
            app.FeedbackVariableDropDown = uidropdown(app.TrajectoryParametersPanel);
            app.FeedbackVariableDropDown.Items = {'Encoder 1', 'Encoder 2'};
            app.FeedbackVariableDropDown.Value = 'Encoder 1';
            app.TrajectoryTypeDropDownLabel = uilabel(app.TrajectoryParametersPanel);
            app.TrajectoryTypeDropDownLabel.Text = 'Trajectory Type:';
            app.TrajectoryTypeDropDown = uidropdown(app.TrajectoryParametersPanel);
            app.TrajectoryTypeDropDown.Items = {'Delta', 'Square Wave', 'Sine Wave', 'Triangle Wave', 'Sawtooth Wave'};
            app.TrajectoryTypeDropDown.Value = 'Delta';
            app.FrequencyLabel = uilabel(app.TrajectoryParametersPanel);
            app.FrequencyLabel.Text = 'Frequency:';
            app.FrequencyEditField = uieditfield(app.TrajectoryParametersPanel, 'numeric');
            app.FeedbackVariableDropDownLabel = uilabel(app.TrajectoryParametersPanel);
            app.FeedbackVariableDropDownLabel.Text = 'Feedback Variable:';
            app.RepsEditFieldLabel = uilabel(app.TrajectoryParametersPanel);
            app.RepsEditFieldLabel.Text = 'Reps:';
            app.RepsEditField = uieditfield(app.TrajectoryParametersPanel, 'numeric');
            app.StartButton = uibutton(app.TrajectoryParametersPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.TabGroup = uitabgroup(app.TrajectoryParametersPanel);
            app.OpenLoopTab = uitab(app.TabGroup);
            app.OpenLoopTab.Title = 'Open Loop';
            app.AmplitudevoltsEditFieldLabel = uilabel(app.OpenLoopTab);
            app.AmplitudevoltsEditFieldLabel.Text = 'Amplitude (volts):';
            app.AmplitudevoltsEditField = uieditfield(app.OpenLoopTab, 'numeric');
            app.ClosedLoopTab = uitab(app.TabGroup);
            app.ClosedLoopTab.Title = 'Closed Loop';
            app.AmplitudeencodercountsEditFieldLabel = uilabel(app.ClosedLoopTab);
            app.AmplitudeencodercountsEditFieldLabel.Text = 'Amplitude (encoder counts)';
            app.AmplitudeencodercountsEditField = uieditfield(app.ClosedLoopTab, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RectilinearECPControl

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end